classdef FinalProject4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MassozEditField              matlab.ui.control.NumericEditField
        MassozEditFieldLabel         matlab.ui.control.Label
        MasslbEditField              matlab.ui.control.NumericEditField
        MasslbEditFieldLabel         matlab.ui.control.Label
        MasskgEditField              matlab.ui.control.NumericEditField
        MasskgEditFieldLabel         matlab.ui.control.Label
        GraphMeanAndStandardDeviationButton  matlab.ui.control.Button
        ConfidenceIntervalLowerEditField  matlab.ui.control.NumericEditField
        ConfidenceIntervalLowerEditFieldLabel  matlab.ui.control.Label
        ConfidenceIntervalUpperEditField  matlab.ui.control.NumericEditField
        ConfidenceIntervalUpperEditFieldLabel  matlab.ui.control.Label
        StandardDeviationEditField   matlab.ui.control.NumericEditField
        VoltageGaugeLabel            matlab.ui.control.Label
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        CalibrationinstructionsTextArea  matlab.ui.control.TextArea
        CalibrationinstructionsTextAreaLabel  matlab.ui.control.Label
        VoltageGauge                 matlab.ui.control.LinearGauge
        CalibrationStatusEditField   matlab.ui.control.EditField
        CalibrationStatusEditFieldLabel  matlab.ui.control.Label
        StartVoltageReadingButton    matlab.ui.control.StateButton
        CurrentTareValueVEditField   matlab.ui.control.NumericEditField
        CurrentTareValueVEditFieldLabel  matlab.ui.control.Label
        CalibrationValueVgEditField  matlab.ui.control.NumericEditField
        CalibrationValueVgEditFieldLabel  matlab.ui.control.Label
        MassgEditField               matlab.ui.control.NumericEditField
        MassgEditFieldLabel          matlab.ui.control.Label
        DoneButton                   matlab.ui.control.Button
        CalibrationWeightEditField   matlab.ui.control.NumericEditField
        CalibrationWeightEditFieldLabel  matlab.ui.control.Label
        CalibrateThisValueButton     matlab.ui.control.Button
        CalibrateButton              matlab.ui.control.Button
        TareButton                   matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
        TareValue = 0 % Voltage Value That Will Equal 0 mass
        numSamples = 1024;
        voltageGenerated = 5;
        calNum = 1

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CalibrationinstructionsTextArea.Visible = "off";
            app.CalibrationWeightEditField.Visible = "off";
            app.CalibrateThisValueButton.Visible = "off";
            app.DoneButton.Visible = "off";
            app.CalibrationWeightEditFieldLabel.Visible = "off";
            app.CalibrationinstructionsTextAreaLabel.Visible = "off";
            %%%%%%%%%%%%%%%%
            app.CalibrationinstructionsTextArea.Value = "Place weight onto the scale equal to the calibration weight and press 'Calibrate This Value.' After all 9 calibration weights press 'Graph' to see the results"
            app.CalibrationStatusEditField.Value = "Not Calibrated"
        end

        % Value changed function: StartVoltageReadingButton
        function StartVoltageReadingButtonValueChanged(app, event)
            value = app.StartVoltageReadingButton.Value;
            if value ==1
                app.GraphMeanAndStandardDeviationButton.Enable = "off";
            else
                app.GraphMeanAndStandardDeviationButton.Enable = "on";
            end
m2k = clib.libm2k.libm2k.context.m2kOpen();
pause(1)
if clibIsNull(m2k)
    clib.libm2k.libm2k.context.contextCloseAll();
    clear m2k
    error("m2k object is null, please restart MATLAB, check device connection or check search path")
end
analogInputObj = m2k.getAnalogIn();
powerSupplyObj = m2k.getPowerSupply();
m2k.calibrateADC();
m2k.calibrateDAC();
%%%            while value == 1 %try here if it does not wor below
powerSupplyObj.enableChannel(0,true);
powerSupplyObj.pushChannel(0,app.voltageGenerated);
analogInputObj.enableChannel(0,true)
powerSupplyObj.enableChannel(1,true);
powerSupplyObj.pushChannel(1,0-app.voltageGenerated);
analogInputObj.enableChannel(1,true)
%%%
            while value == 1
disp(analogInputObj.getVoltage(0))
% TAKING SAMPLES BELOW
clibSamples = analogInputObj.getSamplesInterleaved_matlab(app.numSamples * 2);
clibSamplesArray = double(clibSamples);
sampleArr = clibSamplesArray(1:2:end);
%%%%%%%
meanValue(1) = mean(sampleArr(1:app.numSamples));
stddevValue(1) = std(sampleArr(1:app.numSamples));
meanValue(1) = meanValue(1)-app.TareValue;
meanWeight = meanValue(1) / app.CalibrationValueVgEditField.Value;
app.MassgEditField.Value = meanWeight;
app.MasskgEditField.Value = meanWeight / 1000;
app.MasslbEditField.Value = meanWeight * 0.00220462;
app.MassozEditField.Value = meanWeight * 0.035274;
app.PredictedJellyBeansEditField.Value = meanWeight/1.11;
%%%%OTHER STATS%%%%%%
app.StandardDeviationEditField.Value=stddevValue(1)/app.CalibrationValueVgEditField.Value;
margin = 1.96 * stddevValue(1)/ sqrt(1023)/app.CalibrationValueVgEditField.Value;
app.ConfidenceIntervalUpperEditField.Value=(meanWeight+margin-app.TareValue);
app.ConfidenceIntervalLowerEditField.Value=(meanWeight-margin-app.TareValue);
app.VoltageGauge.Value = meanValue(1);
%%%%%%%%%%%%%%%%%%%

drawnow;
%%%%example code below for number line
%voltageReading = app.m2k.readVoltageCh1();
                %app.TrackCh1EditField.Value = voltageReading;
                %app.VoltageGauge.Value = double(voltageReading);
                %pause(0.01);
                %%%%%%
            end
%%%%%%%
clib.libm2k.libm2k.context.contextCloseAll();
clear m2k
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.CalibrationinstructionsTextArea.Visible = "off";
            app.CalibrationWeightEditField.Visible = "on";
            app.CalibrateThisValueButton.Visible = "on";
            app.DoneButton.Visible = "on";
            app.CalibrationWeightEditFieldLabel.Visible = "on";
            app.CalibrationinstructionsTextAreaLabel.Visible = "off"; 
            app.CalibrationWeightEditField.Value = 0;
            app.UIAxes3.Visible = "on";
        end

        % Button pushed function: TareButton
        function TareButtonPushed(app, event)
app.CurrentTareValueVEditField.Value = app.MassgEditField.Value;
app.TareValue=app.MassgEditField.Value;

        end

        % Button pushed function: CalibrateThisValueButton
        function CalibrateThisValueButtonPushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% clib stands for c library, m2k is a pointer to the m2k object
m2k = clib.libm2k.libm2k.context.m2kOpen();
pause(1)

% check m2k connectivity
if clibIsNull(m2k)
    clib.libm2k.libm2k.context.contextCloseAll();
    clear m2k
    error("m2k object is null, please restart MATLAB, check device connection or check search path")
end

% retrieve analog input object and power supply object
analogInputObj = m2k.getAnalogIn();
powerSupplyObj = m2k.getPowerSupply();

% calibrate ADC and DAC
% the readings could be off if these two lines are not included
m2k.calibrateADC();
m2k.calibrateDAC();

% enable power supply channel 0 (V+)
powerSupplyObj.enableChannel(0,true);

% set the voltage on V+ to 1.7V
powerSupplyObj.pushChannel(0,app.voltageGenerated);

% enable analog input channel 0 (1+, 1-)
analogInputObj.enableChannel(0,true)
% read the voltage between 1+ and 1- and display it
disp(analogInputObj.getVoltage(0))

% number of samples to read from each channel
numOfSample = app.numSamples;

% getSamplesInterleaved_matlab() reads voltages from both channels
clibSamples = analogInputObj.getSamplesInterleaved_matlab(numOfSample * 2);

% copy voltage readings from channel 1 to sampleArr
clibSamplesArray = double(clibSamples);
sampleArr = clibSamplesArray(1:2:end);

meanValue(1) = mean(sampleArr(1:app.numSamples));

% clear m2k object
clib.libm2k.libm2k.context.contextCloseAll();
clear m2k
pause(1)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%CH2%%%%%%%%%%%%%%%%%%%
%{
% clib stands for c library, m2k is a pointer to the m2k object
m2k = clib.libm2k.libm2k.context.m2kOpen();
pause(1)

% check m2k connectivity
if clibIsNull(m2k)
    clib.libm2k.libm2k.context.contextCloseAll();
    clear m2k
    error("m2k object is null, please restart MATLAB, check device connection or check search path")
end

% retrieve analog input object and power supply object
analogInputObj = m2k.getAnalogIn();
powerSupplyObj = m2k.getPowerSupply();

% calibrate ADC and DAC
% the readings could be off if these two lines are not included
m2k.calibrateADC();
m2k.calibrateDAC();

% enable power supply channel 0 (V+)
powerSupplyObj.enableChannel(1,true);

% set the voltage on V+ to 1.7V
powerSupplyObj.pushChannel(1,app.voltageGenerated);

% enable analog input channel 0 (1+, 1-)
analogInputObj.enableChannel(1,true)
% read the voltage between 1+ and 1- and display it
disp(analogInputObj.getVoltage(1))

% number of samples to read from each channel
numOfSample = app.numSamples;

% getSamplesInterleaved_matlab() reads voltages from both channels
clibSamples = analogInputObj.getSamplesInterleaved_matlab(numOfSample * 2);

% copy voltage readings from channel 1 to sampleArr
clibSamplesArray = double(clibSamples);
sampleArr = clibSamplesArray(1:2:end);

meanValue(2) = mean(sampleArr(1:app.numSamples));

% clear m2k object
clib.libm2k.libm2k.context.contextCloseAll();
clear m2k
%}


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
WeightArray(app.calNum) = app.CalibrationWeightEditField.Value;
Voltage1Array(app.calNum) = meanValue(1);
%app.Voltage2Array(app.calNum) = meanValue(2);
app.calNum = app.calNum +1;

%app.CalibrationWeightEditField.Visible = "off";
%app.CalibrationWeightEditField.Visible = "off";
%app.CalibrateThisValueButton.Visible = "off";

outputRange=abs(Voltage1Array(1)-Voltage1Array(end));
fprintf("The output range is %.3f Volt\n", outputRange);
plot(app.UIAxes3, WeightArray,Voltage1Array, '-o', 'LineWidth', 2, 'MarkerSize', 8);
%hold(app.UIAxes3, 'on') 
%plot(app.UIAxes3, app.WeightArray,app.Voltage2Array, '-o', 'LineWidth', 2, 'MarkerSize', 8);
%hold(app.UIAxes3, 'off')
%ylim(app.UIAxes3, [-5 5 ]); 
p = polyfit(WeightArray, Voltage1Array, 1);
slope = p(1);
app.CalibrationValueVgEditField.Value = slope;
        end

        % Callback function
        function PowerSupplyButtonPushed(app, event)

        end

        % Callback function
        function EnterVoltageEditFieldValueChanged(app, event)
            %REMOVED
            %value = app.EnterVoltageEditField.Value;
            %app.voltageGenerated = value;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.UIAxes3.Visible= "on";
            app.CalibrationWeightEditFieldLabel.Visible = "off";
            app.CalibrationWeightEditField.Visible = "off";
            app.CalibrateThisValueButton.Visible = "off";
            app.DoneButton.Visible = "off";
        end

        % Button pushed function: GraphMeanAndStandardDeviationButton
        function GraphMeanAndStandardDeviationButtonPushed(app, event)
%COPY FROM READPOWERSUPPLY SCRIPT            
%{
 libm2k API documentation: https://analogdevicesinc.github.io/libm2k/index.html
 libm2k toolbox download: https://www.mathworks.com/matlabcentral/fileexchange/74385-libm2k-matlab
 Analog Device ADALM-2000 with MATLAB: https://wiki.analog.com/university/tools/m2k/matlab

 To set up the programming environment for interfacing ADALM-2000 using
 MATLAB, please follow these steps:
    1. Download MATLAB 2024b (you must use the 2024b version).
    2. Download the M2K driver on your computer.
    (https://wiki.analog.com/university/tools/pluto/drivers/windows)
    3. Download libm2k toolbox.
    4. Add the libm2k library folder to the MATLAB search path.
 
 Please refer to the document, "Libm2k library Setup in MATLAB 2024b.pdf", for 
 more details regarding step 3 and step 4.


 This script will check if you have set up the environment correctly. 
 The program will:
 1. Create an ADALM-2000 (m2k) object
 2. Set the Power Supply (V+) to 1.7V
 3. Use Analog Input 1 (1+) to read the power supply voltage
 4. Plot the power supply voltage
 4. Clear the m2k object

 Before running this program, please connect:
 1+ to V+
 1- to GND
%}

%clear
% clib stands for c library, m2k is a pointer to the m2k object
m2k = clib.libm2k.libm2k.context.m2kOpen();
pause(1)

% check m2k connectivity
if clibIsNull(m2k)
    clib.libm2k.libm2k.context.contextCloseAll();
    clear m2k
    error("m2k object is null, please restart MATLAB, check device connection or check search path")
end

% retrieve analog input object and power supply object
analogInputObj = m2k.getAnalogIn();
powerSupplyObj = m2k.getPowerSupply();

% calibrate ADC and DAC
% the readings could be off if these two lines are not included
m2k.calibrateADC();
m2k.calibrateDAC();

% enable power supply channel 0 (V+)
powerSupplyObj.enableChannel(0,true);

% set the voltage on V+ to 1.7V
powerSupplyObj.pushChannel(0,1.7);

% enable analog input channel 0 (1+, 1-)
analogInputObj.enableChannel(0,true)
% read the voltage between 1+ and 1- and display it
disp(analogInputObj.getVoltage(0))

% number of samples to read from each channel
numOfSample = 500000;

% getSamplesInterleaved_matlab() reads voltages from both channels
% get 1024 samples from both channels
% the returned array, clibSamples, is a read-only array
% the clibSamples array includes 2048 data points
% the odd indices, clibSamples([1, 3, 5, ...]) are voltage readings from channel 1 (readings between 1+ and 1-)
% the even indices, clibSamples([2, 4, 6, ...]) are voltage readings from channel 2 (readings between 2+ and 2-)
clibSamples = analogInputObj.getSamplesInterleaved_matlab(numOfSample * 2);

% copy voltage readings from channel 1 to sampleArr
% sampleArr(1) = clibSamples(1)
% sampleArr(2) = clibSamples(3)
% sampleArr(3) = clibSamples(5), etc.
clibSamplesArray = double(clibSamples);
sampleArr = clibSamplesArray(1:2:end);

meanValue(1) = mean(sampleArr(1:50000));
meanValue(2) = mean(sampleArr(50001:100000));
meanValue(3) = mean(sampleArr(100001:150000));
meanValue(4) = mean(sampleArr(150001:200000));
meanValue(5) = mean(sampleArr(200001:250000));
meanValue(6) = mean(sampleArr(250001:300000));
meanValue(7) = mean(sampleArr(300001:350000));
meanValue(8) = mean(sampleArr(350001:400000));
meanValue(9) = mean(sampleArr(400001:450000));
meanValue(10) = mean(sampleArr(450001:500000));

stddevValue(1) = std(sampleArr(1:50000));
stddevValue(2) = std(sampleArr(50001:100000));
stddevValue(3) = std(sampleArr(100001:150000));
stddevValue(4) = std(sampleArr(150001:200000));
stddevValue(5) = std(sampleArr(200001:250000));
stddevValue(6) = std(sampleArr(250001:300000));
stddevValue(7) = std(sampleArr(300001:350000));
stddevValue(8) = std(sampleArr(350001:400000));
stddevValue(9) = std(sampleArr(400001:450000));
stddevValue(10) = std(sampleArr(450001:500000));

% plot both arrays
plot(app.UIAxes2, meanValue, 'DisplayName', 'Means');
hold(app.UIAxes2, 'on');
plot(app.UIAxes2, stddevValue, 'DisplayName', 'Standard Deviations');

title(app.UIAxes2, "Means & Standard Deviations Plot");
xlabel(app.UIAxes2, "Time Index (0.5s)");
ylabel(app.UIAxes2, "Voltage (V)");
legend(app.UIAxes2, 'show');
grid(app.UIAxes2, 'on');

% clear m2k object
clib.libm2k.libm2k.context.contextCloseAll();
clear m2k
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Calibration')
            xlabel(app.UIAxes3, 'Weights (g)')
            ylabel(app.UIAxes3, 'Volts')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [0 400 800 1200 1600 2000];
            app.UIAxes3.XTickLabel = {'0'; '400'; '800'; '1200'; '1600'; '2000'};
            app.UIAxes3.YTick = [-5 -2.5 0 2.5 5];
            app.UIAxes3.YTickLabel = {'-5'; '-2.5'; '0'; '2.5'; '5'};
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Mean/StandardDeviation')
            xlabel(app.UIAxes2, 'Time (0.5s)')
            ylabel(app.UIAxes2, 'Voltage (V)')
            zlabel(app.UIAxes2, 'Z')
            app.TareButton = uibutton(app.UIFigure, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButtonPushed, true);
            app.TareButton.Text = 'Tare';
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.CalibrateThisValueButton = uibutton(app.UIFigure, 'push');
            app.CalibrateThisValueButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateThisValueButtonPushed, true);
            app.CalibrateThisValueButton.Text = 'Calibrate This Value';
            app.CalibrationWeightEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationWeightEditFieldLabel.Text = 'CalibrationWeight';
            app.CalibrationWeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.MassgEditFieldLabel = uilabel(app.UIFigure);
            app.MassgEditFieldLabel.Text = 'Mass(g)';
            app.MassgEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalibrationValueVgEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationValueVgEditFieldLabel.Text = 'CalibrationValue (V/g)';
            app.CalibrationValueVgEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentTareValueVEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentTareValueVEditFieldLabel.Text = 'Current Tare Value (V)';
            app.CurrentTareValueVEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartVoltageReadingButton = uibutton(app.UIFigure, 'state');
            app.StartVoltageReadingButton.ValueChangedFcn = createCallbackFcn(app, @StartVoltageReadingButtonValueChanged, true);
            app.StartVoltageReadingButton.Text = 'StartVoltageReading';
            app.CalibrationStatusEditFieldLabel = uilabel(app.UIFigure);
            app.CalibrationStatusEditFieldLabel.Text = 'CalibrationStatus';
            app.CalibrationStatusEditField = uieditfield(app.UIFigure, 'text');
            app.CalibrationStatusEditField.Value = 'Not Calibrated';
            app.VoltageGauge = uigauge(app.UIFigure, 'linear');
            app.VoltageGauge.Limits = [-5 5];
            app.CalibrationinstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.CalibrationinstructionsTextAreaLabel.Text = {'Calibration'; 'instructions'};
            app.CalibrationinstructionsTextArea = uitextarea(app.UIFigure);
            app.CalibrationinstructionsTextArea.Value = {'Place weight onto the scale equal to the calibration weight and press "Calibrate This Value." After all 9 calibration weights press "Graph" to see the results'};
            app.StandardDeviationEditFieldLabel = uilabel(app.UIFigure);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.VoltageGaugeLabel = uilabel(app.UIFigure);
            app.VoltageGaugeLabel.Text = 'Voltage Gauge';
            app.StandardDeviationEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfidenceIntervalUpperEditFieldLabel = uilabel(app.UIFigure);
            app.ConfidenceIntervalUpperEditFieldLabel.Text = 'ConfidenceIntervalUpper';
            app.ConfidenceIntervalUpperEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConfidenceIntervalLowerEditFieldLabel = uilabel(app.UIFigure);
            app.ConfidenceIntervalLowerEditFieldLabel.Text = 'ConfidenceIntervalLower';
            app.ConfidenceIntervalLowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.GraphMeanAndStandardDeviationButton = uibutton(app.UIFigure, 'push');
            app.GraphMeanAndStandardDeviationButton.ButtonPushedFcn = createCallbackFcn(app, @GraphMeanAndStandardDeviationButtonPushed, true);
            app.GraphMeanAndStandardDeviationButton.Text = 'Graph Mean And Standard Deviation ';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass(kg)';
            app.MasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasslbEditFieldLabel = uilabel(app.UIFigure);
            app.MasslbEditFieldLabel.Text = 'Mass(lb)';
            app.MasslbEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassozEditFieldLabel = uilabel(app.UIFigure);
            app.MassozEditFieldLabel.Text = 'Mass(oz)';
            app.MassozEditField = uieditfield(app.UIFigure, 'numeric');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end